classdef FIRFilter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        PlottheoriginalSincButton      matlab.ui.control.Button
        FilterLengthLabel              matlab.ui.control.Label
        FilterLengthEditField          matlab.ui.control.NumericEditField
        SelectWindowDropDownLabel      matlab.ui.control.Label
        SelectWindowDropDown           matlab.ui.control.DropDown
        linearmagnitudeCheckBox        matlab.ui.control.CheckBox
        logscaleCheckBox               matlab.ui.control.CheckBox
        PleaseenterthefollowingdataLabel  matlab.ui.control.Label
        ThenClickwhatdouwantHereLabel  matlab.ui.control.Label
        PlotMyWindowButton             matlab.ui.control.Button
        PlotMyFilterButton             matlab.ui.control.Button
        FsinHzEditFieldLabel           matlab.ui.control.Label
        FsinHzEditField                matlab.ui.control.NumericEditField
        FcinHzEditFieldLabel           matlab.ui.control.Label
        FcinHzEditField                matlab.ui.control.NumericEditField
        BetaforkaiserwindowLabel       matlab.ui.control.Label
        BetaforkaiserwindowEditField   matlab.ui.control.NumericEditField
    end

    methods (Access = private)

        % Button pushed function: PlottheoriginalSincButton
        function amplitudeSliderValueChanged2(app, event)
            clc;
            N =  app.FilterLengthEditField.Value;
            fs = app.FsinHzEditField.Value;     % Sampling frequency
            fc = app.FcinHzEditField.Value;
            
            n = -(N/2) : (N/2)-1; % floor to avoid if N is even
            fc_normalized = fc / fs; % Normalize cutoff frequency by sampling frequency
            sinc_func = sin(2*pi*(fc_normalized)*n) ./ (pi*n);  % Sinc function (ideal low-pass filter)
            sinc_func(n == 0) = 2 * (fc_normalized);  % Handle the division by zero at n=0
            
            
            plot(app.UIAxes, n, sinc_func, 'b', 'LineWidth', 2); % Plot in App Designer Axes
            title(app.UIAxes, 'Sinc Wave');
            xlabel(app.UIAxes, 'Time');
            ylabel(app.UIAxes, 'Amplitude');
            grid(app.UIAxes, 'on');
            
        end

        % Button pushed function: PlotMyWindowButton
        function PlotMyWindowButtonPushed(app, event)
            clc;
            Window_Name = app.SelectWindowDropDown.Value; % geting the name of the window
            N =  app.FilterLengthEditField.Value;  % geting the length of the window from gui
            kaiser_Beta = app.BetaforkaiserwindowEditField.Value;
            
            if strcmp(Window_Name, 'rectangular')
                rect_window = rectwin(N)' ;
                n = -(N/2) : (N/2)-1;  % Symmetric for freq axis
                plot(app.UIAxes, n, rect_window, 'b', 'LineWidth', 2); % Plot in App Designer Axes
                title(app.UIAxes, 'The rect window');
                xlabel(app.UIAxes, 'Time');
                ylabel(app.UIAxes, 'Amplitude');
                grid(app.UIAxes, 'on');
                
            elseif strcmp(Window_Name, 'Blackman')
                
                Blackman_Window = blackman(N);
                n = -(N/2) : (N/2)-1;  % Symmetric for freq axis
                plot(app.UIAxes, n, Blackman_Window, 'b', 'LineWidth', 2); % Plot in App Designer Axes
                title(app.UIAxes, 'The Blackman Window');
                xlabel(app.UIAxes, 'Time');
                ylabel(app.UIAxes, 'Amplitude');
                grid(app.UIAxes, 'on');
                
            elseif strcmp(Window_Name, 'Chebyshev')
                Chebyshev_Window = chebwin(N);
                n = -(N/2) : (N/2)-1;  % Symmetric for freq axis
                plot(app.UIAxes, n, Chebyshev_Window, 'b', 'LineWidth', 2); % Plot in App Designer Axes
                title(app.UIAxes, 'The Chebyshev Window');
                xlabel(app.UIAxes, 'Time');
                ylabel(app.UIAxes, 'Amplitude');
                grid(app.UIAxes, 'on');
                
            elseif strcmp(Window_Name, 'Kaiser')
                
                Kaiser_Window = kaiser(N , kaiser_Beta);
                n = -(N/2) : (N/2)-1;  % Symmetric for freq axis
                plot(app.UIAxes, n, Kaiser_Window, 'b', 'LineWidth', 2); % Plot in App Designer Axes
                title(app.UIAxes, 'The kaiser Window');
                xlabel(app.UIAxes, 'Time');
                ylabel(app.UIAxes, 'Amplitude');
                grid(app.UIAxes, 'on');
                
            end
            
            
        end

        % Button pushed function: PlotMyFilterButton
        function PlotMyFilterButtonPushed(app, event)
            clc;
            
            Window_Name = app.SelectWindowDropDown.Value; % geting the name of the window
            N =  app.FilterLengthEditField.Value;  % geting the length of the window from gui
            fs = app.FsinHzEditField.Value;     % getting the Sampling frequency
            fc = app.FcinHzEditField.Value;   % getting the cut off frequency
            kaiser_Beta = app.BetaforkaiserwindowEditField.Value;
            
            Linear_Scale = app.linearmagnitudeCheckBox.Value;
            Log_Scale    = app.logscaleCheckBox.Value;
            
            n = -(N/2) : (N/2)-1; % floor to avoid if N is even
            fc_normalized = fc / fs; % Normalize cutoff frequency by sampling frequency
            sinc_func = sin(2*pi*(fc_normalized)*n) ./ (pi*n);  % Sinc function (ideal low-pass filter)
            sinc_func(n == 0) = 2 * (fc_normalized);  % Handle the division by zero at n=0
            f = linspace(-fs/2 , fs/2 , length(sinc_func) * 10);  % * 10 for zero padding in ploting
            
            
            if strcmp(Window_Name, 'rectangular')
                
                rect_window = rectwin(N)' ;
                low_pass_filter = sinc_func .* rect_window;
                low_pass_filter = low_pass_filter / sum(low_pass_filter);
                
                save('rect_filter.mat', 'low_pass_filter');
                
                
                fft_magnitude = fftshift(abs(fft(low_pass_filter , N * 10)) ); % * 10 for zero padding in ploting
                
                if Linear_Scale
                    plot(app.UIAxes, f, fft_magnitude, 'b', 'LineWidth', 2); % Plot in App Designer Axes
                    title(app.UIAxes, 'Linear Scale of low pass filter With Blackman Window');
                    
                elseif Log_Scale
                    plot(app.UIAxes, f, 20*log10(fft_magnitude), 'b', 'LineWidth', 2); % Plot in App Designer Axes
                    title(app.UIAxes, 'Log Scale of low pass filter With Blackman Window');
                    
                else
                    plot(app.UIAxes, f, fft_magnitude, 'b', 'LineWidth', 2); % Plot in App Designer Axes
                    title(app.UIAxes, 'Linear Scale of low pass filter With Blackman Window');
                end
                
                
                title(app.UIAxes, 'low pass filter With Rect Window');
                xlabel(app.UIAxes, 'frequency');
                ylabel(app.UIAxes, 'Amplitude');
                grid(app.UIAxes, 'on');


            elseif strcmp(Window_Name, 'Blackman')
                
                Blackman_Window = blackman(N)';
                
                low_pass_filter = sinc_func .* Blackman_Window;
                low_pass_filter = low_pass_filter / sum(low_pass_filter);
                
                save('blackman_filter.mat', 'low_pass_filter');
                
                
                fft_magnitude = fftshift(abs(fft(low_pass_filter , N  * 10)) ); % * 10 for zero padding in ploting
                
                if Linear_Scale
                    plot(app.UIAxes, f, fft_magnitude, 'b', 'LineWidth', 2); % Plot in App Designer Axes
                    title(app.UIAxes, 'Linear Scale of low pass filter With Blackman Window');
                    
                elseif Log_Scale
                    plot(app.UIAxes, f, 20*log10(fft_magnitude), 'b', 'LineWidth', 2); % Plot in App Designer Axes
                    title(app.UIAxes, 'Log Scale of low pass filter With Blackman Window');
                    
                else
                    plot(app.UIAxes, f, fft_magnitude, 'b', 'LineWidth', 2); % Plot in App Designer Axes
                    title(app.UIAxes, 'Linear Scale of low pass filter With Blackman Window');
                end
                
                xlabel(app.UIAxes, 'frequency');
                ylabel(app.UIAxes, 'Amplitude');
                grid(app.UIAxes, 'on');
                
                
            elseif strcmp(Window_Name, 'Chebyshev')
                
                Chebyshev_Window = chebwin(N)';
                low_pass_filter = sinc_func .* Chebyshev_Window;
                low_pass_filter = low_pass_filter / sum(low_pass_filter);
                
                save('Chebyshev_filter.mat', 'low_pass_filter');
                
                
                fft_magnitude = fftshift(abs(fft(low_pass_filter , N * 10)) ); % * 10 for zero padding in ploting
                
                if Linear_Scale
                    plot(app.UIAxes, f, fft_magnitude, 'b', 'LineWidth', 2); % Plot in App Designer Axes
                    title(app.UIAxes, 'Linear Scale of low pass filter With Blackman Window');
                    
                elseif Log_Scale
                    plot(app.UIAxes, f, 20*log10(fft_magnitude), 'b', 'LineWidth', 2); % Plot in App Designer Axes
                    title(app.UIAxes, 'Log Scale of low pass filter With Blackman Window');
                    
                else
                    plot(app.UIAxes, f, fft_magnitude, 'b', 'LineWidth', 2); % Plot in App Designer Axes
                    title(app.UIAxes, 'Linear Scale of low pass filter With Blackman Window');
                end
                
                title(app.UIAxes, 'low pass filter With Chebyshev Window');
                xlabel(app.UIAxes, 'frequency');
                ylabel(app.UIAxes, 'Amplitude');
                grid(app.UIAxes, 'on');
                
            elseif strcmp(Window_Name, 'Kaiser')
                
                Kaiser_Window = kaiser(N , kaiser_Beta)';
                low_pass_filter = sinc_func .* Kaiser_Window;
                low_pass_filter = low_pass_filter / sum(low_pass_filter);
                
                save('Kaiser_filter.mat', 'low_pass_filter');
                
                fft_magnitude = fftshift(abs(fft(low_pass_filter , N  * 10)) ); % * 10 for zero padding in ploting
                
                if Linear_Scale
                    plot(app.UIAxes, f, fft_magnitude, 'b', 'LineWidth', 2); % Plot in App Designer Axes
                    title(app.UIAxes, 'Linear Scale of low pass filter With Blackman Window');
                    
                elseif Log_Scale
                    plot(app.UIAxes, f, 20*log10(fft_magnitude), 'b', 'LineWidth', 2); % Plot in App Designer Axes
                    title(app.UIAxes, 'Log Scale of low pass filter With Blackman Window');
                    
                else
                    plot(app.UIAxes, f, fft_magnitude, 'b', 'LineWidth', 2); % Plot in App Designer Axes
                    title(app.UIAxes, 'Linear Scale of low pass filter With Blackman Window');
                end
                
                
                title(app.UIAxes, 'low pass filter With Kaiser Window');
                xlabel(app.UIAxes, 'frequency');
                ylabel(app.UIAxes, 'Amplitude');
                grid(app.UIAxes, 'on');
                
            end
            
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Sinc Function')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.PlottheoriginalSincButton = uibutton(app.UIFigure, 'push');
            app.PlottheoriginalSincButton.ButtonPushedFcn = createCallbackFcn(app, @amplitudeSliderValueChanged2, true);
            app.PlottheoriginalSincButton.Text = 'Plot the original Sinc';
            app.FilterLengthLabel = uilabel(app.UIFigure);
            app.FilterLengthLabel.Text = 'Filter Length   ';
            app.FilterLengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.SelectWindowDropDownLabel = uilabel(app.UIFigure);
            app.SelectWindowDropDownLabel.Text = 'Select Window';
            app.SelectWindowDropDown = uidropdown(app.UIFigure);
            app.SelectWindowDropDown.Items = {'rectangular', 'Blackman', 'Chebyshev', 'Kaiser'};
            app.SelectWindowDropDown.Value = 'rectangular';
            app.linearmagnitudeCheckBox = uicheckbox(app.UIFigure);
            app.linearmagnitudeCheckBox.Text = 'linear magnitude ';
            app.logscaleCheckBox = uicheckbox(app.UIFigure);
            app.logscaleCheckBox.Text = 'log scale';
            app.PleaseenterthefollowingdataLabel = uilabel(app.UIFigure);
            app.PleaseenterthefollowingdataLabel.Text = 'Please enter the following data';
            app.ThenClickwhatdouwantHereLabel = uilabel(app.UIFigure);
            app.ThenClickwhatdouwantHereLabel.Text = 'Then Click what do u want Here';
            app.PlotMyWindowButton = uibutton(app.UIFigure, 'push');
            app.PlotMyWindowButton.ButtonPushedFcn = createCallbackFcn(app, @PlotMyWindowButtonPushed, true);
            app.PlotMyWindowButton.Text = 'Plot My Window';
            app.PlotMyFilterButton = uibutton(app.UIFigure, 'push');
            app.PlotMyFilterButton.ButtonPushedFcn = createCallbackFcn(app, @PlotMyFilterButtonPushed, true);
            app.PlotMyFilterButton.Text = 'Plot My Filter    ';
            app.FsinHzEditFieldLabel = uilabel(app.UIFigure);
            app.FsinHzEditFieldLabel.Text = 'Fs  in Hz';
            app.FsinHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.FcinHzEditFieldLabel = uilabel(app.UIFigure);
            app.FcinHzEditFieldLabel.Text = 'Fc in Hz  ';
            app.FcinHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.BetaforkaiserwindowLabel = uilabel(app.UIFigure);
            app.BetaforkaiserwindowLabel.Text = 'Beta for kaiser window';
            app.BetaforkaiserwindowEditField = uieditfield(app.UIFigure, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = FIRFilter

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end