classdef LeastFir < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        least                         matlab.ui.control.UIAxes
        wiegted                       matlab.ui.control.UIAxes
        LeastSquaresLSFIRfilterdesignLabel  matlab.ui.control.Label
        FcEditFieldLabel              matlab.ui.control.Label
        FcEditField                   matlab.ui.control.NumericEditField
        FsEditFieldLabel              matlab.ui.control.Label
        FsEditField                   matlab.ui.control.NumericEditField
        FilterLengthEditFieldLabel    matlab.ui.control.Label
        FilterLengthEditField         matlab.ui.control.NumericEditField
        pointsinthedesiredfrequencyresponseLabel  matlab.ui.control.Label
        pointsinthedesiredfrequencyresponseEditField  matlab.ui.control.NumericEditField
        DesignLeastSquaresButton      matlab.ui.control.Button
        DesignWeightedLeastSquaresButton  matlab.ui.control.Button
        FsEditField_2Label            matlab.ui.control.Label
        FsEditField_2                 matlab.ui.control.NumericEditField
        Label                         matlab.ui.control.Label
        FpassEditFieldLabel           matlab.ui.control.Label
        FpassEditField                matlab.ui.control.NumericEditField
        FstopEditFieldLabel           matlab.ui.control.Label
        FstopEditField                matlab.ui.control.NumericEditField
        FilterLengthEditField_2Label  matlab.ui.control.Label
        FilterLengthEditField_2       matlab.ui.control.NumericEditField
        pointsinthedesiredfrequencyresponseEditField_2Label  matlab.ui.control.Label
        pointsinthedesiredfrequencyresponseEditField_2  matlab.ui.control.NumericEditField
        W_Pass_PointsEditFieldLabel   matlab.ui.control.Label
        W_Pass_PointsEditField        matlab.ui.control.NumericEditField
        W_Stop_PointsEditFieldLabel   matlab.ui.control.Label
        W_Stop_PointsEditField        matlab.ui.control.NumericEditField
        W_TransPointsEditFieldLabel   matlab.ui.control.Label
        W_TransPointsEditField        matlab.ui.control.NumericEditField
        LogScaleCheckBox              matlab.ui.control.CheckBox
        LogScaleCheckBox_2            matlab.ui.control.CheckBox
    end

    methods (Access = private)

        % Button pushed function: DesignLeastSquaresButton
        function DesignLeastSquaresButtonPushed(app, event)
            clc;
            
            scale = app.LogScaleCheckBox.Value;
            
            %warring look for the zero bading in fft i set it a fixed number 4096
            
            N_Ls = app.FilterLengthEditField.Value; % Filter Length
            
            % this is to check and ensure the length of the filter is odd
            % and make it to half to work on the postive half and after we will
            % flip it beacouse the filter is a symtric filter
            
            if(mod(N_Ls , 2))
                
                N_Ls = (N_Ls - 1) / 2;
                
            else
                N_Ls = N_Ls / 2;
            end


            K_Desired = app.pointsinthedesiredfrequencyresponseEditField.Value; % number of points in the desired frequency response
            
            fs = app.FsEditField.Value; % sampling freq
            
            Fc = app.FcEditField.Value; % cutoff frequency
            
            H_desired_W = linspace(0 , fs / 2 , K_Desired); % to make a linespace for h desired just init number of points-
            
            H_desired = double(H_desired_W < Fc); % to set the points befor the fc to one and after it to zero
            
            
            H_Last_desired = H_desired'; % init the H desired Matrix for Least squered filter


            % now we will make the F Matrix which contain the
            % 1 .... 2cos(w1*1) .... 2 cos(w2*2) ..... 2cos(wk*k);
            
            % this code makes the F Matrix
            
            F_Matrix = zeros(K_Desired , N_Ls); % init for the F matrix with Zeros
            
            F_Matrix(:,1) = 1; % make the first column ones
            for counter = 1 : K_Desired
                
                for ii = 2 : N_Ls % -1 beacouse the h(0)
                    
                    % ii = 2 to start from the index 2 becouse the first column is ones
                    omega = 2 * pi * (H_desired_W(counter)/fs); % normilezed omega where H_Last_desired(ii) = Wn
                    
                    F_Matrix(counter,ii) = 2 * cos(omega * (ii-1) ); %where ii-1 = n start from 1
                    
                end
                
            end


            h_LS = (F_Matrix' * F_Matrix) \ (F_Matrix' * H_Last_desired);
            
            
            H_Coff_Fliped = [flipud(h_LS(2:end)); h_LS];
            
            
            H_Fft = fft(H_Coff_Fliped , 4096); % the 4096 for zero bading
            
            
            f = linspace(-fs/2 , fs/2 , 4096); % the 4096 for zero bading u can add more zero bading if u need
            
            if scale
                plot(app.least, f,20*log10(fftshift(abs(H_Fft))) , 'LineWidth' , 1.5);
            else
                plot(app.least, f,fftshift(abs(H_Fft)) , 'LineWidth' , 1.5);
            end
            title(app.least, 'Least Squares Fir Filter');
            xlabel(app.least, 'Freq');
            ylabel(app.least, 'Amplitude');
            grid(app.least, 'on');
            
            
        end

        % Button pushed function: DesignWeightedLeastSquaresButton
        function DesignWeightedLeastSquaresButtonPushed(app, event)
            clc;
            
            w_scale = app.LogScaleCheckBox_2.Value;


            W_N_Ls = app.FilterLengthEditField_2.Value; % Filter Length
            W_K_Desired = app.pointsinthedesiredfrequencyresponseEditField_2.Value; % number of points in the desired frequency response
            W_fs = app.FsEditField_2.Value; % sampling freq
            
            W_F_Pass = app.FpassEditField.Value; % stop band frequency
            W_F_Stop = app.FstopEditField.Value; % F stop band
            
            
            W_Weights_Pass = app.W_Pass_PointsEditField.Value;
            W_Weights_Trans = app.W_TransPointsEditField.Value;
            W_Weights_Stop = app.W_Stop_PointsEditField.Value;
            
            Number_Of_Weights = W_K_Desired;


            if(mod(W_N_Ls , 2))
                
                W_N_Ls = (W_N_Ls - 1) / 2;
                
            else
                W_N_Ls = W_N_Ls / 2;
            end


            Weights_Matrix = zeros(W_K_Desired , W_K_Desired); % init the matrix of the Weights_Matrix for zeros


            H_desired_W = linspace(0 , W_fs / 2 , W_K_Desired); % to make a linespace for h desired just init number of points-
            
            H_desired = double(H_desired_W < W_F_Pass); % to set the points befor the fc to one and after it to zero
            
            
            Pass_Band_No_Weights = sum(H_desired); % the number of points that will be Weights for pass band
            
            Trans_Band_No_Weights = sum(( double(H_desired_W < W_F_Stop)  ) ) - Pass_Band_No_Weights ; % the number of points that will be Weights for transion region


            for count = 1 : Pass_Band_No_Weights
                
                Weights_Matrix(count , count) = W_Weights_Pass;
            end
            
            for count = Pass_Band_No_Weights : (Pass_Band_No_Weights + Trans_Band_No_Weights)
                
                Weights_Matrix(count , count) = W_Weights_Trans;
            end
            
            for count = (Pass_Band_No_Weights + Trans_Band_No_Weights) : Number_Of_Weights
                
                Weights_Matrix(count , count) = W_Weights_Stop;
            end


            H_Last_desired = H_desired'; % init the H desired Matrix for Least squered filter


            % now we will make the F Matrix which contain the
            % 1 .... 2cos(w1*1) .... 2 cos(w2*2) ..... 2cos(wk*k);
            
            % this code makes the F Matrix
            
            F_Matrix = zeros(W_K_Desired , W_N_Ls); % init for the F matrix with Zeros
            
            F_Matrix(:,1) = 1; % make the first column ones
            for counter = 1 : W_K_Desired
                
                for ii = 2 : W_N_Ls % -1 beacouse the h(0)
                    
                    % ii = 2 to start from the index 2 becouse the first column is ones
                    omega = 2 * pi * (H_desired_W(counter)/W_fs); % normilezed omega where H_Last_desired(ii) = Wn
                    
                    F_Matrix(counter,ii) = 2 * cos(omega * (ii-1) ); %where ii-1 = n start from 1
                    
                end
                
            end


            h_LS = (F_Matrix' * Weights_Matrix * F_Matrix) \ (F_Matrix' * Weights_Matrix * H_Last_desired);
            
            
            H_Coff_Fliped = [flipud(h_LS(2:end)); h_LS];
            
            save H_Coff_Fliped;
            
            H_Fft = fft(H_Coff_Fliped , 4096);
            
            
            f = linspace(-W_fs/2 , W_fs/2 , 4096);
            
            if w_scale
                plot(app.wiegted, f,20*log10(fftshift(abs(H_Fft))) , 'LineWidth' , 1.5);
            else
                plot(app.wiegted, f,fftshift(abs(H_Fft)) , 'LineWidth' , 1.5);
            end
            
            
            title(app.wiegted, 'Weighted Least Squares Fir Filter');
            xlabel(app.wiegted, 'Freq');
            ylabel(app.wiegted, 'Amplitude');
            grid(app.wiegted, 'on');
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.least = uiaxes(app.UIFigure);
            title(app.least, 'Least Squares (LS) FIR filter ')
            xlabel(app.least, 'X')
            ylabel(app.least, 'Y')
            app.wiegted = uiaxes(app.UIFigure);
            title(app.wiegted, 'Weighted Least Squares (WLS) FIR filter ')
            xlabel(app.wiegted, 'X')
            ylabel(app.wiegted, 'Y')
            app.LeastSquaresLSFIRfilterdesignLabel = uilabel(app.UIFigure);
            app.LeastSquaresLSFIRfilterdesignLabel.Text = 'Least Squares (LS) FIR filter design ';
            app.FcEditFieldLabel = uilabel(app.UIFigure);
            app.FcEditFieldLabel.Text = 'Fc';
            app.FcEditField = uieditfield(app.UIFigure, 'numeric');
            app.FsEditFieldLabel = uilabel(app.UIFigure);
            app.FsEditFieldLabel.Text = 'Fs';
            app.FsEditField = uieditfield(app.UIFigure, 'numeric');
            app.FilterLengthEditFieldLabel = uilabel(app.UIFigure);
            app.FilterLengthEditFieldLabel.Text = 'Filter Length';
            app.FilterLengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.pointsinthedesiredfrequencyresponseLabel = uilabel(app.UIFigure);
            app.pointsinthedesiredfrequencyresponseLabel.Text = {'points in the desired'; ' frequency response'};
            app.pointsinthedesiredfrequencyresponseEditField = uieditfield(app.UIFigure, 'numeric');
            app.DesignLeastSquaresButton = uibutton(app.UIFigure, 'push');
            app.DesignLeastSquaresButton.ButtonPushedFcn = createCallbackFcn(app, @DesignLeastSquaresButtonPushed, true);
            app.DesignLeastSquaresButton.Text = 'Design Least Squares';
            app.DesignWeightedLeastSquaresButton = uibutton(app.UIFigure, 'push');
            app.DesignWeightedLeastSquaresButton.ButtonPushedFcn = createCallbackFcn(app, @DesignWeightedLeastSquaresButtonPushed, true);
            app.DesignWeightedLeastSquaresButton.Text = 'Design Weighted Least Squares';
            app.FsEditField_2Label = uilabel(app.UIFigure);
            app.FsEditField_2Label.Text = 'Fs';
            app.FsEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '__________________________________________';
            app.FpassEditFieldLabel = uilabel(app.UIFigure);
            app.FpassEditFieldLabel.Text = 'Fpass';
            app.FpassEditField = uieditfield(app.UIFigure, 'numeric');
            app.FstopEditFieldLabel = uilabel(app.UIFigure);
            app.FstopEditFieldLabel.Text = 'Fstop';
            app.FstopEditField = uieditfield(app.UIFigure, 'numeric');
            app.FilterLengthEditField_2Label = uilabel(app.UIFigure);
            app.FilterLengthEditField_2Label.Text = 'Filter Length';
            app.FilterLengthEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.pointsinthedesiredfrequencyresponseEditField_2Label = uilabel(app.UIFigure);
            app.pointsinthedesiredfrequencyresponseEditField_2Label.Text = {'points in the desired'; ' frequency response'};
            app.pointsinthedesiredfrequencyresponseEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.W_Pass_PointsEditFieldLabel = uilabel(app.UIFigure);
            app.W_Pass_PointsEditFieldLabel.Text = 'W_Pass_Points';
            app.W_Pass_PointsEditField = uieditfield(app.UIFigure, 'numeric');
            app.W_Stop_PointsEditFieldLabel = uilabel(app.UIFigure);
            app.W_Stop_PointsEditFieldLabel.Text = 'W_Stop_Points';
            app.W_Stop_PointsEditField = uieditfield(app.UIFigure, 'numeric');
            app.W_TransPointsEditFieldLabel = uilabel(app.UIFigure);
            app.W_TransPointsEditFieldLabel.Text = 'W_TransPoints';
            app.W_TransPointsEditField = uieditfield(app.UIFigure, 'numeric');
            app.LogScaleCheckBox = uicheckbox(app.UIFigure);
            app.LogScaleCheckBox.Text = 'Log Scale';
            app.LogScaleCheckBox_2 = uicheckbox(app.UIFigure);
            app.LogScaleCheckBox_2.Text = 'Log Scale';
        end
    end

    methods (Access = public)

        % Construct app
        function app = LeastFir

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end